classdef tubes2app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        Nomor1Tab                 matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout4               matlab.ui.container.GridLayout
        GridLayout6               matlab.ui.container.GridLayout
        ProcessButton             matlab.ui.control.Button
        InputButton               matlab.ui.control.Button
        GridLayout5               matlab.ui.container.GridLayout
        logLabel                  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        MatrixConvTextArea        matlab.ui.control.TextArea
        MatrixConvTextAreaLabel   matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        Nomor23Tab                matlab.ui.container.Tab
        GridLayout2_2             matlab.ui.container.GridLayout
        GridLayout4_2             matlab.ui.container.GridLayout
        GridLayout6_2             matlab.ui.container.GridLayout
        ProcessButton_2           matlab.ui.control.Button
        InputButton_2             matlab.ui.control.Button
        GridLayout5_2             matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        nEditField                matlab.ui.control.NumericEditField
        nEditFieldLabel           matlab.ui.control.Label
        d0EditField               matlab.ui.control.NumericEditField
        d0EditFieldLabel          matlab.ui.control.Label
        ButtonGroup               matlab.ui.container.ButtonGroup
        BHPFButton                matlab.ui.control.RadioButton
        GHPFButton                matlab.ui.control.RadioButton
        IHPFButton                matlab.ui.control.RadioButton
        BLPFButton                matlab.ui.control.RadioButton
        GLPFButton                matlab.ui.control.RadioButton
        ILPFButton                matlab.ui.control.RadioButton
        GaussianButton            matlab.ui.control.RadioButton
        MeanButton                matlab.ui.control.RadioButton
        logLabel_2                matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        GridLayout3_2             matlab.ui.container.GridLayout
        Image2_2                  matlab.ui.control.Image
        Image_2                   matlab.ui.control.Image
        Nomor4Tab                 matlab.ui.container.Tab
        GridLayout2_3             matlab.ui.container.GridLayout
        GridLayout4_3             matlab.ui.container.GridLayout
        GridLayout6_3             matlab.ui.container.GridLayout
        ProcessButton_3           matlab.ui.control.Button
        InputButton_3             matlab.ui.control.Button
        GridLayout5_3             matlab.ui.container.GridLayout
        GridLayout7_3             matlab.ui.container.GridLayout
        LowGammaEditField         matlab.ui.control.NumericEditField
        LowGammaEditFieldLabel    matlab.ui.control.Label
        HighGammaEditField        matlab.ui.control.NumericEditField
        HighGammaEditFieldLabel   matlab.ui.control.Label
        GridLayout7_2             matlab.ui.container.GridLayout
        d0EditField_2             matlab.ui.control.NumericEditField
        d0EditField_2Label        matlab.ui.control.Label
        logLabel_3                matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        GridLayout3_3             matlab.ui.container.GridLayout
        Image2_3                  matlab.ui.control.Image
        Image_3                   matlab.ui.control.Image
        Nomor5Tab                 matlab.ui.container.Tab
        GridLayout2_4             matlab.ui.container.GridLayout
        GridLayout4_4             matlab.ui.container.GridLayout
        GridLayout7_4             matlab.ui.container.GridLayout
        TipeNoiseDropDown         matlab.ui.control.DropDown
        TipeNoiseDropDownLabel    matlab.ui.control.Label
        TipeFilterDropDown        matlab.ui.control.DropDown
        TipeFilterDropDownLabel   matlab.ui.control.Label
        GridLayout6_4             matlab.ui.container.GridLayout
        ProcessButton_5           matlab.ui.control.Button
        InputButton_5             matlab.ui.control.Button
        GridLayout3_4             matlab.ui.container.GridLayout
        FilteredImageLabel        matlab.ui.control.Label
        NoisyImageLabel           matlab.ui.control.Label
        OriginalImageLabel        matlab.ui.control.Label
        Image4_2                  matlab.ui.control.Image
        Image2_4                  matlab.ui.control.Image
        Image_4                   matlab.ui.control.Image
        Nomor6Tab                 matlab.ui.container.Tab
        GridLayout8               matlab.ui.container.GridLayout
        GridLayout13              matlab.ui.container.GridLayout
        Image5                    matlab.ui.control.Image
        Image4                    matlab.ui.control.Image
        GridLayout10              matlab.ui.container.GridLayout
        TabGroup2                 matlab.ui.container.TabGroup
        BandrejectTab             matlab.ui.container.Tab
        GridLayout11              matlab.ui.container.GridLayout
        nEditField_2              matlab.ui.control.NumericEditField
        nEditField_2Label         matlab.ui.control.Label
        WEditField                matlab.ui.control.NumericEditField
        WEditFieldLabel           matlab.ui.control.Label
        D0EditField               matlab.ui.control.NumericEditField
        D0EditFieldLabel          matlab.ui.control.Label
        TipeDropDown              matlab.ui.control.DropDown
        TipeDropDownLabel         matlab.ui.control.Label
        BandpassTab               matlab.ui.container.Tab
        GridLayout11_2            matlab.ui.container.GridLayout
        nEditField_4              matlab.ui.control.NumericEditField
        nEditField_4Label         matlab.ui.control.Label
        WEditField_2              matlab.ui.control.NumericEditField
        WEditField_2Label         matlab.ui.control.Label
        D0EditField_2             matlab.ui.control.NumericEditField
        D0EditField_2Label        matlab.ui.control.Label
        TipeDropDown_2            matlab.ui.control.DropDown
        TipeDropDown_2Label       matlab.ui.control.Label
        NotchTab                  matlab.ui.container.Tab
        GridLayout12              matlab.ui.container.GridLayout
        TargetcolEditField        matlab.ui.control.NumericEditField
        TargetcolEditFieldLabel   matlab.ui.control.Label
        TargetrowEditField        matlab.ui.control.NumericEditField
        TargetrowEditFieldLabel   matlab.ui.control.Label
        nEditField_3              matlab.ui.control.NumericEditField
        nEditField_3Label         matlab.ui.control.Label
        D0EditField_3             matlab.ui.control.NumericEditField
        D0EditField_3Label        matlab.ui.control.Label
        ProcessButton_4           matlab.ui.control.Button
        InputButton_4             matlab.ui.control.Button
        Nomor7Tab                 matlab.ui.container.Tab
        GridLayout2_5             matlab.ui.container.GridLayout
        GridLayout4_5             matlab.ui.container.GridLayout
        GridLayout7_5             matlab.ui.container.GridLayout
        EstNoiseEditField         matlab.ui.control.NumericEditField
        EstNoiseEditFieldLabel    matlab.ui.control.Label
        NoiseMeanEditField        matlab.ui.control.NumericEditField
        NoiseMeanEditFieldLabel   matlab.ui.control.Label
        NoiseVarEditField         matlab.ui.control.NumericEditField
        NoiseVarEditFieldLabel    matlab.ui.control.Label
        BlurTethaEditField        matlab.ui.control.NumericEditField
        BlurTethaEditFieldLabel   matlab.ui.control.Label
        BlurLengthEditField       matlab.ui.control.NumericEditField
        BlurLengthEditFieldLabel  matlab.ui.control.Label
        GridLayout6_5             matlab.ui.container.GridLayout
        ProcessButton_6           matlab.ui.control.Button
        InputButton_6             matlab.ui.control.Button
        GridLayout3_5             matlab.ui.container.GridLayout
        RestoredImageLabel        matlab.ui.control.Label
        BlurredImageLabel         matlab.ui.control.Label
        OriginalImageLabel_2      matlab.ui.control.Label
        Image4_3                  matlab.ui.control.Image
        Image2_5                  matlab.ui.control.Image
        Image_5                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        inputImage0 % Description
        inputImage1
        inputImage2
        imgprcs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imgprcs = ImageProcessor;
        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage0 = image;
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image.ImageSource = app.inputImage0;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.Label.Text = '';
            % Get the input text from the Text Area
            inputText = app.MatrixConvTextArea.Value;
        
            try
                % Convert the text input to a matrix
                matrix = str2num(char(inputText)); % Convert text to a matrix using str2num

                % Check if the conversion was successful
                if isempty(matrix)
                    error('Invalid matrix format. Please enter a valid matrix.');
                elseif size(matrix, 1) ~= size(matrix, 2)
                    error('Invalid matrix size. Please enter NxN matrix.');
                elseif isempty(app.inputImage0)
                    error('No image inputed.');
                end
                
                app.Label.Text = 'Processing...';
                drawnow;

                R = app.imgprcs.conv(app.inputImage0(:, :, 1), matrix);
                G = app.imgprcs.conv(app.inputImage0(:, :, 2), matrix);
                B = app.imgprcs.conv(app.inputImage0(:, :, 3), matrix);
                app.Label.Text = 'Done';

                app.Image2.ImageSource = cat(3, R, G, B);
            catch ME
                % Display error message if input is invalid
                app.Label.Text = ME.message;
                disp(ME.message);
            end
        end

        % Button pushed function: InputButton_2
        function InputButton_2Pushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage1 = image;
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image_2.ImageSource = app.inputImage1;
            end
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
            app.Label_2.Text = '';
        
            try
                d0 = app.d0EditField.Value;
                n = app.nEditField.Value;
                % Check if the conversion was successful
                if isempty(app.inputImage1)
                    error('No image inputed.');
                elseif n < 0
                    error('n is positive integer.');
                elseif d0 < 0
                    error('d0 is positive integer.');
                end
                
                R = app.inputImage1(:, :, 1);
                G = app.inputImage1(:, :, 2);
                B = app.inputImage1(:, :, 3);

                selectedButton = app.ButtonGroup.SelectedObject;

                app.Label_2.Text = 'Processing...';
                drawnow;

                switch selectedButton.Text
                    case 'Mean'
                        [r, c] = size(R);
                        s = min(r, c);
                        s = min(floor(s/20), 11);
                        if mod(s, 2) == 0
                            s = s + 1;
                        end
                        mask = ones(s, s) / (s * s);
                        R = app.imgprcs.conv(R, mask);
                        G = app.imgprcs.conv(G, mask);
                        B = app.imgprcs.conv(B, mask);
                    case 'Gaussian'
                        [r, c] = size(R);
                        s = min(r, c);
                        s = min(floor(s/20), 21);
                        if mod(s, 2) == 0
                            s = s + 1;
                        end
                        % Create a Gaussian mask of size n x n
                        sigma = 1;  % Standard deviation of the Gaussian
                        half_n = floor(s / 2);  % Half the size to create the grid
                        [x, y] = meshgrid(-half_n:half_n, -half_n:half_n);  % Create a grid
                        
                        % Gaussian function
                        mask = exp(-(x.^2 + y.^2) / (2 * sigma^2));
                        
                        % Normalize the mask so that the sum is 1
                        mask = mask / sum(mask(:));  % Normalize the mask

                        R = app.imgprcs.conv(R, mask);
                        G = app.imgprcs.conv(G, mask);
                        B = app.imgprcs.conv(B, mask);
                    case 'ILPF'
                        R = app.imgprcs.ipf(R, d0, false);
                        G = app.imgprcs.ipf(G, d0, false);
                        B = app.imgprcs.ipf(B, d0, false);
                    case 'GLPF'
                        R = app.imgprcs.gpf(R, d0, false);
                        G = app.imgprcs.gpf(G, d0, false);
                        B = app.imgprcs.gpf(B, d0, false);
                    case 'BLPF'
                        R = app.imgprcs.bpf(R, d0, n, false);
                        G = app.imgprcs.bpf(G, d0, n, false);
                        B = app.imgprcs.bpf(B, d0, n, false);
                    case 'IHPF'
                        R = app.imgprcs.ipf(R, d0, true);
                        G = app.imgprcs.ipf(G, d0, true);
                        B = app.imgprcs.ipf(B, d0, true);
                    case 'GHPF'
                        R = app.imgprcs.gpf(R, d0, true);
                        G = app.imgprcs.gpf(G, d0, true);
                        B = app.imgprcs.gpf(B, d0, true);
                    case 'BHPF'
                        R = app.imgprcs.bpf(R, d0, n, true);
                        G = app.imgprcs.bpf(G, d0, n, true);
                        B = app.imgprcs.bpf(B, d0, n, true);
                end

                app.Label_2.Text = 'Done';

                app.Image2_2.ImageSource = cat(3, R, G, B);
            catch ME
                % Display error message if input is invalid
                app.Label_2.Text = ME.message;
                disp(ME.message);
            end
        end

        % Button pushed function: InputButton_3
        function InputButton_3Pushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage2 = image;
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image_3.ImageSource = app.inputImage2;
            end
        end

        % Button pushed function: ProcessButton_3
        function ProcessButton_3Pushed(app, event)
            app.Label_3.Text = '';
        
            try
                d0 = app.d0EditField_2.Value;
                hg = app.HighGammaEditField.Value;
                lg = app.LowGammaEditField.Value;
                % Check if the conversion was successful
                if isempty(app.inputImage2)
                    error('No image inputed.');
                elseif d0 < 0
                    error('d0 is positive integer.');
                elseif isempty(hg)
                    error('hg must not be empty.');
                elseif isempty(lg)
                    error('lg must not be empty.');
                end
                
                R = app.inputImage2(:, :, 1);
                G = app.inputImage2(:, :, 2);
                B = app.inputImage2(:, :, 3);

                app.Label_3.Text = 'Processing...';
                drawnow;

                R = app.imgprcs.pikirsendiri(R, d0, hg, lg, false);
                G = app.imgprcs.pikirsendiri(G, d0, hg, lg, false);
                B = app.imgprcs.pikirsendiri(B, d0, hg, lg, false);

                app.Label_3.Text = 'Done';

                app.Image2_3.ImageSource = cat(3, R, G, B);
            catch ME
                % Display error message if input is invalid
                app.Label_3.Text = ME.message;
                disp(ME.message);
            end
        end

        % Button pushed function: InputButton_4
        function InputButton_4Pushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage2 = image;

                app.Image4.ImageSource = image;
            end
        end

        % Button pushed function: ProcessButton_4
        function ProcessButton_4Pushed(app, event)
            filter_type = app.TabGroup2.SelectedTab.Title;
            
            image = app.inputImage2;
            
            if strcmp(filter_type, 'Bandreject')
                type = app.TipeDropDown.Value;
                D0 = app.D0EditField.Value;
                W = app.WEditField.Value;
                n = app.nEditField.Value;
                if type == 'Ideal'
                    result = bandrejectFilter(image, "ideal", D0, W, n);
                elseif type == 'Butterworth'
                    result = bandrejectFilter(image, "butterworth", D0, W, n);
                elseif type == 'Gaussian'
                    result = bandrejectFilter(image, "gaussian", D0, W, n);
                end
            elseif strcmp(filter_type, 'Bandpass')
                type = app.TipeDropDown_2.Value;
                D0 = app.D0EditField_2.Value;
                W = app.WEditField_2.Value;
                n = app.nEditField_4.Value;
                if type == 'Ideal'
                    result = bandpassFilter(image, "ideal", D0, W, n);
                elseif type == 'Butterworth'
                    result = bandpassFilter(image, "butterworth", D0, W, n);
                elseif type == 'Gaussian'
                    result = bandpassFilter(image, "gaussian", D0, W, n);
                end
            elseif strcmp(filter_type, 'Notch')
                D0 = app.D0EditField_3.Value;
                n = app.nEditField_3.Value;
                target_row = app.TargetrowEditField.Value;
                target_col = app.TargetcolEditField.Value;
                
                result = notchFilter(image, D0, n, target_row, target_col);
                image = cat(3, result, result, result);
                app.Image4.ImageSource = image;
            end

            app.Image5.ImageSource = cat(3, result, result, result);
        end

        % Button pushed function: InputButton_5
        function InputButton_5Pushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage2 = image;

                app.Image_4.ImageSource = image;
            end
        end

        % Button pushed function: ProcessButton_5
        function ProcessButton_5Pushed(app, event)
            img = app.inputImage2;
            noise_image = noiseImage(img, app.TipeNoiseDropDown.Value);

            app.Image2_4.ImageSource = noise_image;

            image_result = noiseRemoval(noise_image, app.TipeFilterDropDown.Value);

            app.Image4_2.ImageSource = image_result;
        end

        % Button pushed function: InputButton_6
        function InputButton_6Pushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage2 = image;

                app.Image_5.ImageSource = image;
            end
        end

        % Button pushed function: ProcessButton_6
        function ProcessButton_6Pushed(app, event)
            img = app.inputImage2;
            length = app.BlurLengthEditField.Value;
            tetha = app.BlurTethaEditField.Value;
            noiseVar = app.NoiseVarEditField.Value;
            noiseMean = app.NoiseMeanEditField.Value;
            estimated_nsr = app.EstNoiseEditField.Value;

            blurred_image = motionBlurImage(img, length, tetha, noiseMean, noiseVar);

            app.Image2_5.ImageSource = blurred_image;

            restored_image = wienerFilter(blurred_image, length, tetha, noiseMean, noiseVar, estimated_nsr);

            app.Image4_3.ImageSource = restored_image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Nomor1Tab = uitab(app.TabGroup);
            app.Nomor1Tab.Title = 'Nomor 1';
            app.GridLayout2 = uigridlayout(app.Nomor1Tab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout3);
            app.Image2 = uiimage(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.MatrixConvTextAreaLabel = uilabel(app.GridLayout5);
            app.MatrixConvTextAreaLabel.Text = 'Matrix Conv';
            app.MatrixConvTextArea = uitextarea(app.GridLayout5);
            app.MatrixConvTextArea.Placeholder = 'nxn matrix e.g. [1 1 1; 1 1 1; 1 1 1], ones(9 , 9) / 81, etc.';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '';
            app.logLabel = uilabel(app.GridLayout5);
            app.logLabel.Text = 'log:';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.InputButton = uibutton(app.GridLayout6, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.ProcessButton = uibutton(app.GridLayout6, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Nomor23Tab = uitab(app.TabGroup);
            app.Nomor23Tab.Title = 'Nomor 2 & 3';
            app.GridLayout2_2 = uigridlayout(app.Nomor23Tab);
            app.GridLayout3_2 = uigridlayout(app.GridLayout2_2);
            app.Image_2 = uiimage(app.GridLayout3_2);
            app.Image2_2 = uiimage(app.GridLayout3_2);
            app.GridLayout4_2 = uigridlayout(app.GridLayout2_2);
            app.GridLayout5_2 = uigridlayout(app.GridLayout4_2);
            app.Label_2 = uilabel(app.GridLayout5_2);
            app.Label_2.Text = '';
            app.logLabel_2 = uilabel(app.GridLayout5_2);
            app.logLabel_2.Text = 'log:';
            app.ButtonGroup = uibuttongroup(app.GridLayout5_2);
            app.ButtonGroup.Title = 'Button Group';
            app.MeanButton = uiradiobutton(app.ButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.GaussianButton = uiradiobutton(app.ButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.ILPFButton = uiradiobutton(app.ButtonGroup);
            app.ILPFButton.Text = 'ILPF';
            app.GLPFButton = uiradiobutton(app.ButtonGroup);
            app.GLPFButton.Text = 'GLPF';
            app.BLPFButton = uiradiobutton(app.ButtonGroup);
            app.BLPFButton.Text = 'BLPF';
            app.IHPFButton = uiradiobutton(app.ButtonGroup);
            app.IHPFButton.Text = 'IHPF';
            app.GHPFButton = uiradiobutton(app.ButtonGroup);
            app.GHPFButton.Text = 'GHPF';
            app.BHPFButton = uiradiobutton(app.ButtonGroup);
            app.BHPFButton.Text = 'BHPF';
            app.GridLayout7 = uigridlayout(app.GridLayout5_2);
            app.d0EditFieldLabel = uilabel(app.GridLayout7);
            app.d0EditFieldLabel.Text = 'd0';
            app.d0EditField = uieditfield(app.GridLayout7, 'numeric');
            app.d0EditField.Value = 50;
            app.nEditFieldLabel = uilabel(app.GridLayout7);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridLayout7, 'numeric');
            app.nEditField.Value = 2;
            app.GridLayout6_2 = uigridlayout(app.GridLayout4_2);
            app.InputButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.InputButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputButton_2Pushed, true);
            app.InputButton_2.Text = 'Input';
            app.ProcessButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.Nomor4Tab = uitab(app.TabGroup);
            app.Nomor4Tab.Title = 'Nomor 4';
            app.GridLayout2_3 = uigridlayout(app.Nomor4Tab);
            app.GridLayout3_3 = uigridlayout(app.GridLayout2_3);
            app.Image_3 = uiimage(app.GridLayout3_3);
            app.Image2_3 = uiimage(app.GridLayout3_3);
            app.GridLayout4_3 = uigridlayout(app.GridLayout2_3);
            app.GridLayout5_3 = uigridlayout(app.GridLayout4_3);
            app.Label_3 = uilabel(app.GridLayout5_3);
            app.Label_3.Text = '';
            app.logLabel_3 = uilabel(app.GridLayout5_3);
            app.logLabel_3.Text = 'log:';
            app.GridLayout7_2 = uigridlayout(app.GridLayout5_3);
            app.d0EditField_2Label = uilabel(app.GridLayout7_2);
            app.d0EditField_2Label.Text = 'd0';
            app.d0EditField_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.d0EditField_2.Value = 50;
            app.GridLayout7_3 = uigridlayout(app.GridLayout5_3);
            app.HighGammaEditFieldLabel = uilabel(app.GridLayout7_3);
            app.HighGammaEditFieldLabel.Text = 'High Gamma';
            app.HighGammaEditField = uieditfield(app.GridLayout7_3, 'numeric');
            app.HighGammaEditField.Value = 1.01;
            app.LowGammaEditFieldLabel = uilabel(app.GridLayout7_3);
            app.LowGammaEditFieldLabel.Text = 'Low Gamma';
            app.LowGammaEditField = uieditfield(app.GridLayout7_3, 'numeric');
            app.LowGammaEditField.Value = 0.99;
            app.GridLayout6_3 = uigridlayout(app.GridLayout4_3);
            app.InputButton_3 = uibutton(app.GridLayout6_3, 'push');
            app.InputButton_3.ButtonPushedFcn = createCallbackFcn(app, @InputButton_3Pushed, true);
            app.InputButton_3.Text = 'Input';
            app.ProcessButton_3 = uibutton(app.GridLayout6_3, 'push');
            app.ProcessButton_3.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_3Pushed, true);
            app.ProcessButton_3.Text = 'Process';
            app.Nomor5Tab = uitab(app.TabGroup);
            app.Nomor5Tab.Title = 'Nomor 5';
            app.GridLayout2_4 = uigridlayout(app.Nomor5Tab);
            app.GridLayout3_4 = uigridlayout(app.GridLayout2_4);
            app.Image_4 = uiimage(app.GridLayout3_4);
            app.Image2_4 = uiimage(app.GridLayout3_4);
            app.Image4_2 = uiimage(app.GridLayout3_4);
            app.OriginalImageLabel = uilabel(app.GridLayout3_4);
            app.OriginalImageLabel.Text = 'Original Image';
            app.NoisyImageLabel = uilabel(app.GridLayout3_4);
            app.NoisyImageLabel.Text = 'Noisy Image';
            app.FilteredImageLabel = uilabel(app.GridLayout3_4);
            app.FilteredImageLabel.Text = 'Filtered Image';
            app.GridLayout4_4 = uigridlayout(app.GridLayout2_4);
            app.GridLayout6_4 = uigridlayout(app.GridLayout4_4);
            app.InputButton_5 = uibutton(app.GridLayout6_4, 'push');
            app.InputButton_5.ButtonPushedFcn = createCallbackFcn(app, @InputButton_5Pushed, true);
            app.InputButton_5.Text = 'Input';
            app.ProcessButton_5 = uibutton(app.GridLayout6_4, 'push');
            app.ProcessButton_5.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_5Pushed, true);
            app.ProcessButton_5.Text = 'Process';
            app.GridLayout7_4 = uigridlayout(app.GridLayout4_4);
            app.TipeFilterDropDownLabel = uilabel(app.GridLayout7_4);
            app.TipeFilterDropDownLabel.Text = 'Tipe Filter';
            app.TipeFilterDropDown = uidropdown(app.GridLayout7_4);
            app.TipeFilterDropDown.Items = {'Minimum', 'Maximum', 'Median', 'Arithmetic Mean', 'Geometric Mean', 'Harmonic Mean', 'Contraharmonic Mean', 'Midpoint', 'Alpha-trimmed Mean'};
            app.TipeFilterDropDown.Value = 'Minimum';
            app.TipeNoiseDropDownLabel = uilabel(app.GridLayout7_4);
            app.TipeNoiseDropDownLabel.Text = 'Tipe Noise';
            app.TipeNoiseDropDown = uidropdown(app.GridLayout7_4);
            app.TipeNoiseDropDown.Items = {'Salt & Pepper', 'Gaussian'};
            app.TipeNoiseDropDown.Value = 'Salt & Pepper';
            app.Nomor6Tab = uitab(app.TabGroup);
            app.Nomor6Tab.Title = 'Nomor 6';
            app.GridLayout8 = uigridlayout(app.Nomor6Tab);
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.InputButton_4 = uibutton(app.GridLayout10, 'push');
            app.InputButton_4.ButtonPushedFcn = createCallbackFcn(app, @InputButton_4Pushed, true);
            app.InputButton_4.Text = 'Input';
            app.ProcessButton_4 = uibutton(app.GridLayout10, 'push');
            app.ProcessButton_4.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_4Pushed, true);
            app.ProcessButton_4.Text = 'Process';
            app.TabGroup2 = uitabgroup(app.GridLayout10);
            app.BandrejectTab = uitab(app.TabGroup2);
            app.BandrejectTab.Title = 'Bandreject';
            app.GridLayout11 = uigridlayout(app.BandrejectTab);
            app.TipeDropDownLabel = uilabel(app.GridLayout11);
            app.TipeDropDownLabel.Text = 'Tipe';
            app.TipeDropDown = uidropdown(app.GridLayout11);
            app.TipeDropDown.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.TipeDropDown.Value = 'Ideal';
            app.D0EditFieldLabel = uilabel(app.GridLayout11);
            app.D0EditFieldLabel.Text = 'D0';
            app.D0EditField = uieditfield(app.GridLayout11, 'numeric');
            app.D0EditField.Value = 50;
            app.WEditFieldLabel = uilabel(app.GridLayout11);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.GridLayout11, 'numeric');
            app.WEditField.Value = 5;
            app.nEditField_2Label = uilabel(app.GridLayout11);
            app.nEditField_2Label.Text = 'n';
            app.nEditField_2 = uieditfield(app.GridLayout11, 'numeric');
            app.nEditField_2.Value = 1;
            app.BandpassTab = uitab(app.TabGroup2);
            app.BandpassTab.Title = 'Bandpass';
            app.GridLayout11_2 = uigridlayout(app.BandpassTab);
            app.TipeDropDown_2Label = uilabel(app.GridLayout11_2);
            app.TipeDropDown_2Label.Text = 'Tipe';
            app.TipeDropDown_2 = uidropdown(app.GridLayout11_2);
            app.TipeDropDown_2.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.TipeDropDown_2.Value = 'Ideal';
            app.D0EditField_2Label = uilabel(app.GridLayout11_2);
            app.D0EditField_2Label.Text = 'D0';
            app.D0EditField_2 = uieditfield(app.GridLayout11_2, 'numeric');
            app.D0EditField_2.Value = 50;
            app.WEditField_2Label = uilabel(app.GridLayout11_2);
            app.WEditField_2Label.Text = 'W';
            app.WEditField_2 = uieditfield(app.GridLayout11_2, 'numeric');
            app.WEditField_2.Value = 5;
            app.nEditField_4Label = uilabel(app.GridLayout11_2);
            app.nEditField_4Label.Text = 'n';
            app.nEditField_4 = uieditfield(app.GridLayout11_2, 'numeric');
            app.nEditField_4.Value = 1;
            app.NotchTab = uitab(app.TabGroup2);
            app.NotchTab.Title = 'Notch';
            app.GridLayout12 = uigridlayout(app.NotchTab);
            app.D0EditField_3Label = uilabel(app.GridLayout12);
            app.D0EditField_3Label.Text = 'D0';
            app.D0EditField_3 = uieditfield(app.GridLayout12, 'numeric');
            app.D0EditField_3.Value = 5;
            app.nEditField_3Label = uilabel(app.GridLayout12);
            app.nEditField_3Label.Text = 'n';
            app.nEditField_3 = uieditfield(app.GridLayout12, 'numeric');
            app.nEditField_3.Value = 1;
            app.TargetrowEditFieldLabel = uilabel(app.GridLayout12);
            app.TargetrowEditFieldLabel.Text = 'Target row ';
            app.TargetrowEditField = uieditfield(app.GridLayout12, 'numeric');
            app.TargetrowEditField.Value = 10;
            app.TargetcolEditFieldLabel = uilabel(app.GridLayout12);
            app.TargetcolEditFieldLabel.Text = 'Target col';
            app.TargetcolEditField = uieditfield(app.GridLayout12, 'numeric');
            app.TargetcolEditField.Value = 10;
            app.GridLayout13 = uigridlayout(app.GridLayout8);
            app.Image4 = uiimage(app.GridLayout13);
            app.Image5 = uiimage(app.GridLayout13);
            app.Nomor7Tab = uitab(app.TabGroup);
            app.Nomor7Tab.Title = 'Nomor 7';
            app.GridLayout2_5 = uigridlayout(app.Nomor7Tab);
            app.GridLayout3_5 = uigridlayout(app.GridLayout2_5);
            app.Image_5 = uiimage(app.GridLayout3_5);
            app.Image2_5 = uiimage(app.GridLayout3_5);
            app.Image4_3 = uiimage(app.GridLayout3_5);
            app.OriginalImageLabel_2 = uilabel(app.GridLayout3_5);
            app.OriginalImageLabel_2.Text = 'Original Image';
            app.BlurredImageLabel = uilabel(app.GridLayout3_5);
            app.BlurredImageLabel.Text = 'Blurred Image';
            app.RestoredImageLabel = uilabel(app.GridLayout3_5);
            app.RestoredImageLabel.Text = 'Restored Image';
            app.GridLayout4_5 = uigridlayout(app.GridLayout2_5);
            app.GridLayout6_5 = uigridlayout(app.GridLayout4_5);
            app.InputButton_6 = uibutton(app.GridLayout6_5, 'push');
            app.InputButton_6.ButtonPushedFcn = createCallbackFcn(app, @InputButton_6Pushed, true);
            app.InputButton_6.Text = 'Input';
            app.ProcessButton_6 = uibutton(app.GridLayout6_5, 'push');
            app.ProcessButton_6.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_6Pushed, true);
            app.ProcessButton_6.Text = 'Process';
            app.GridLayout7_5 = uigridlayout(app.GridLayout4_5);
            app.BlurLengthEditFieldLabel = uilabel(app.GridLayout7_5);
            app.BlurLengthEditFieldLabel.Text = 'Blur Length';
            app.BlurLengthEditField = uieditfield(app.GridLayout7_5, 'numeric');
            app.BlurTethaEditFieldLabel = uilabel(app.GridLayout7_5);
            app.BlurTethaEditFieldLabel.Text = 'Blur Tetha';
            app.BlurTethaEditField = uieditfield(app.GridLayout7_5, 'numeric');
            app.NoiseVarEditFieldLabel = uilabel(app.GridLayout7_5);
            app.NoiseVarEditFieldLabel.Text = 'Noise Var';
            app.NoiseVarEditField = uieditfield(app.GridLayout7_5, 'numeric');
            app.NoiseMeanEditFieldLabel = uilabel(app.GridLayout7_5);
            app.NoiseMeanEditFieldLabel.Text = 'Noise Mean';
            app.NoiseMeanEditField = uieditfield(app.GridLayout7_5, 'numeric');
            app.EstNoiseEditFieldLabel = uilabel(app.GridLayout7_5);
            app.EstNoiseEditFieldLabel.Text = 'Est Noise';
            app.EstNoiseEditField = uieditfield(app.GridLayout7_5, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tubes2app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end